classdef GUIproyectoVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        imagenBase             matlab.ui.control.Image
        CargaunaimagenButton   matlab.ui.control.Button
        DetectarobjetosButton  matlab.ui.control.Button
        ResultadoPanel         matlab.ui.container.Panel
        tituloCoches           matlab.ui.control.Label
        numPersParaCruzarText  matlab.ui.control.Label
        tituloPersonas         matlab.ui.control.Label
        numCochesText          matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageParaProcesar % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaunaimagenButton
        function CargaunaimagenButtonPushed(app, event)
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
                warndlg('File not selected');
                return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
                errordlg('The image must be colored');
                return
            end
            
            app.imagenBase.ImageSource = image;
        end

        % Button pushed function: DetectarobjetosButton
        function DetectarobjetosButtonPushed(app, event)
           imageSource = app.imagenBase.ImageSource;
            
           elementos = procesarImg(string(num2str(imageSource)));
           
           app.numCochesText.Text = num2str(elementos(1));
           app.numPersParaCruzarText.Text = num2str(elementos(2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.imagenBase = uiimage(app.UIFigure);
            app.imagenBase.ImageSource = 'imagPasoCebra2.jpg';
            app.CargaunaimagenButton = uibutton(app.UIFigure, 'push');
            app.CargaunaimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargaunaimagenButtonPushed, true);
            app.CargaunaimagenButton.Text = 'Carga una imagen';
            app.DetectarobjetosButton = uibutton(app.UIFigure, 'push');
            app.DetectarobjetosButton.ButtonPushedFcn = createCallbackFcn(app, @DetectarobjetosButtonPushed, true);
            app.DetectarobjetosButton.Text = 'Detectar objetos';
            app.ResultadoPanel = uipanel(app.UIFigure);
            app.ResultadoPanel.Title = 'Resultado:';
            app.tituloCoches = uilabel(app.ResultadoPanel);
            app.tituloCoches.Text = 'Nº de Coches:';
            app.numPersParaCruzarText = uilabel(app.ResultadoPanel);
            app.numPersParaCruzarText.Text = '...';
            app.tituloPersonas = uilabel(app.ResultadoPanel);
            app.tituloPersonas.Text = 'Nº de personas para cruzar:';
            app.numCochesText = uilabel(app.ResultadoPanel);
            app.numCochesText.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIproyectoVision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end